classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        SelectImageTab      matlab.ui.container.Tab
        SelectImageButton   matlab.ui.control.Button
        kiri                matlab.ui.container.Panel
        ImageSourceLabel    matlab.ui.control.Label
        path                matlab.ui.control.EditField
        DetectUSBPortTab    matlab.ui.container.Tab
        DetectAudioJackTab  matlab.ui.container.Tab
        DetectLanPortTab    matlab.ui.container.Tab
        DetectVGAPortTab    matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function kepencet(app, event)
            [fileName, path] = uigetfile('*.jpg;*.png;*.bmp');
            
            app.path.Value = fullfile(path, fileName);
%             app.hiddenData.Text = ;
            figure(app.UIFigure);
%             app.kiri
            tg.audiojack = app.DetectAudioJackTab;
            tg.usbport = app.DetectUSBPortTab;
            tg.lanport = app.DetectLanPortTab;
            tg.vgaport = app.DetectVGAPortTab; 
            processImage(app.path.Value,app.kiri);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectImageTab = uitab(app.TabGroup);
            app.SelectImageTab.Title = 'Select Image';
            app.SelectImageButton = uibutton(app.SelectImageTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @kepencet, true);
            app.SelectImageButton.Text = 'Select Image';
            app.kiri = uipanel(app.SelectImageTab);
            app.kiri.Title = 'Panel';
            app.ImageSourceLabel = uilabel(app.SelectImageTab);
            app.ImageSourceLabel.Text = 'Image Source';
            app.path = uieditfield(app.SelectImageTab, 'text');
            app.DetectUSBPortTab = uitab(app.TabGroup);
            app.DetectUSBPortTab.Title = 'Detect USB Port';
            app.DetectAudioJackTab = uitab(app.TabGroup);
            app.DetectAudioJackTab.Title = 'Detect Audio Jack';
            app.DetectLanPortTab = uitab(app.TabGroup);
            app.DetectLanPortTab.Title = 'Detect Lan Port';
            app.DetectVGAPortTab = uitab(app.TabGroup);
            app.DetectVGAPortTab.Title = 'Detect VGA Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end